classdef MusicSimAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        VirtualDataSim       matlab.ui.container.Tab
        BaseInfo             matlab.ui.container.Panel
        Label                matlab.ui.control.Label
        CentralFreq          matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        SampleFreq           matlab.ui.control.NumericEditField
        ChannelSNR           matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        ArrayInfo            matlab.ui.container.Panel
        Label_4              matlab.ui.control.Label
        ArrayType            matlab.ui.control.DropDown
        Label_5              matlab.ui.control.Label
        ArrayNum             matlab.ui.control.NumericEditField
        Label_6              matlab.ui.control.Label
        Radius               matlab.ui.control.NumericEditField
        Button               matlab.ui.control.Button
        SigInfo              matlab.ui.container.Panel
        GridLayout9          matlab.ui.container.GridLayout
        Sig4                 matlab.ui.container.Panel
        GridLayout6          matlab.ui.container.GridLayout
        Pitch4               matlab.ui.control.NumericEditField
        EditField_11Label_2  matlab.ui.control.Label
        Azimuth4             matlab.ui.control.NumericEditField
        EditField_12Label_2  matlab.ui.control.Label
        CwFreq4              matlab.ui.control.NumericEditField
        EditField_13Label_2  matlab.ui.control.Label
        SNR4                 matlab.ui.control.NumericEditField
        EditField_14Label_2  matlab.ui.control.Label
        BW4                  matlab.ui.control.NumericEditField
        EditField_15Label_2  matlab.ui.control.Label
        SelectSig4           matlab.ui.control.CheckBox
        Sig3                 matlab.ui.container.Panel
        GridLayout12         matlab.ui.container.GridLayout
        SelectSig3           matlab.ui.control.CheckBox
        Pitch3               matlab.ui.control.NumericEditField
        EditField_11Label    matlab.ui.control.Label
        Azimuth3             matlab.ui.control.NumericEditField
        EditField_12Label    matlab.ui.control.Label
        CwFreq3              matlab.ui.control.NumericEditField
        EditField_13Label    matlab.ui.control.Label
        SNR3                 matlab.ui.control.NumericEditField
        EditField_14Label    matlab.ui.control.Label
        BW3                  matlab.ui.control.NumericEditField
        EditField_15Label    matlab.ui.control.Label
        Sig2                 matlab.ui.container.Panel
        GridLayout11         matlab.ui.container.GridLayout
        SelectSig2           matlab.ui.control.CheckBox
        Pitch2               matlab.ui.control.NumericEditField
        EditField_6Label     matlab.ui.control.Label
        Azimuth2             matlab.ui.control.NumericEditField
        EditField_7Label     matlab.ui.control.Label
        SNR2                 matlab.ui.control.NumericEditField
        EditField_9Label     matlab.ui.control.Label
        BW2                  matlab.ui.control.NumericEditField
        EditField_10Label    matlab.ui.control.Label
        CwFreq2              matlab.ui.control.NumericEditField
        EditField_8Label     matlab.ui.control.Label
        Sig1                 matlab.ui.container.Panel
        GridLayout10         matlab.ui.container.GridLayout
        SelectSig1           matlab.ui.control.CheckBox
        Label_7              matlab.ui.control.Label
        Pitch1               matlab.ui.control.NumericEditField
        EditField_2Label     matlab.ui.control.Label
        Azimuth1             matlab.ui.control.NumericEditField
        Label_8              matlab.ui.control.Label
        CwFreq1              matlab.ui.control.NumericEditField
        EditField_4Label     matlab.ui.control.Label
        SNR1                 matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        BW1                  matlab.ui.control.NumericEditField
        RealDataSim          matlab.ui.container.Tab
        Panel                matlab.ui.container.Panel
        FileList             matlab.ui.control.ListBox
        Button_3             matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        Antenna1             matlab.ui.control.CheckBox
        XEditFieldLabel      matlab.ui.control.Label
        X1                   matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        Y1                   matlab.ui.control.NumericEditField
        Antenna2             matlab.ui.control.CheckBox
        XEditField_2Label    matlab.ui.control.Label
        X2                   matlab.ui.control.NumericEditField
        YEditField_2Label    matlab.ui.control.Label
        Y2                   matlab.ui.control.NumericEditField
        Antenna3             matlab.ui.control.CheckBox
        XEditField_3Label    matlab.ui.control.Label
        X3                   matlab.ui.control.NumericEditField
        YEditField_3Label    matlab.ui.control.Label
        Y3                   matlab.ui.control.NumericEditField
        Antenna4             matlab.ui.control.CheckBox
        XEditField_4Label    matlab.ui.control.Label
        X4                   matlab.ui.control.NumericEditField
        YEditField_4Label    matlab.ui.control.Label
        Y4                   matlab.ui.control.NumericEditField
        Antenna5             matlab.ui.control.CheckBox
        XEditField_5Label    matlab.ui.control.Label
        X5                   matlab.ui.control.NumericEditField
        YEditField_5Label    matlab.ui.control.Label
        Y5                   matlab.ui.control.NumericEditField
        Antenna6             matlab.ui.control.CheckBox
        XEditField_6Label    matlab.ui.control.Label
        X6                   matlab.ui.control.NumericEditField
        YEditField_6Label    matlab.ui.control.Label
        Y6                   matlab.ui.control.NumericEditField
        Antenna7             matlab.ui.control.CheckBox
        XEditField_7Label    matlab.ui.control.Label
        X7                   matlab.ui.control.NumericEditField
        YEditField_7Label    matlab.ui.control.Label
        Y7                   matlab.ui.control.NumericEditField
        Antenna8             matlab.ui.control.CheckBox
        XEditField_8Label    matlab.ui.control.Label
        X8                   matlab.ui.control.NumericEditField
        YEditField_8Label    matlab.ui.control.Label
        Y8                   matlab.ui.control.NumericEditField
        Simu2                matlab.ui.control.Button
        Simu2_2              matlab.ui.control.Button
        Calibration          matlab.ui.container.Tab
        Calibrating          matlab.ui.control.Button
        SelBaseCh            matlab.ui.container.ButtonGroup
        SelCH1               matlab.ui.control.RadioButton
        SelCH2               matlab.ui.control.RadioButton
        SelCH3               matlab.ui.control.RadioButton
        SelCH4               matlab.ui.control.RadioButton
        SelCH5               matlab.ui.control.RadioButton
        SelCH6               matlab.ui.control.RadioButton
        SelCH7               matlab.ui.control.RadioButton
        SelCH8               matlab.ui.control.RadioButton
        Label_9              matlab.ui.control.Label
        DirPath              matlab.ui.control.EditField
        Button_4             matlab.ui.control.Button
        TimePara             matlab.ui.container.Panel
        Label_11             matlab.ui.control.Label
        Cali_StartTime       matlab.ui.control.NumericEditField
        Label_12             matlab.ui.control.Label
        Cali_StopTime        matlab.ui.control.NumericEditField
        IQEditFieldLabel     matlab.ui.control.Label
        IQ_StartTime         matlab.ui.control.NumericEditField
        Panel_4              matlab.ui.container.Panel
        Label_10             matlab.ui.control.Label
        Fs                   matlab.ui.control.NumericEditField
        Label_13             matlab.ui.control.Label
        InterMul             matlab.ui.control.NumericEditField
        Label_14             matlab.ui.control.Label
        OutliersTh           matlab.ui.control.NumericEditField
        Panel_5              matlab.ui.container.Panel
        Cali_Antenna1        matlab.ui.control.CheckBox
        XEditFieldLabel_2    matlab.ui.control.Label
        CaliX1               matlab.ui.control.NumericEditField
        YEditFieldLabel_2    matlab.ui.control.Label
        CaliY1               matlab.ui.control.NumericEditField
        Cali_Antenna2        matlab.ui.control.CheckBox
        XEditField_2Label_2  matlab.ui.control.Label
        CaliX2               matlab.ui.control.NumericEditField
        YEditField_2Label_2  matlab.ui.control.Label
        CaliY2               matlab.ui.control.NumericEditField
        Cali_Antenna3        matlab.ui.control.CheckBox
        XEditField_3Label_2  matlab.ui.control.Label
        CaliX3               matlab.ui.control.NumericEditField
        YEditField_3Label_2  matlab.ui.control.Label
        CaliY3               matlab.ui.control.NumericEditField
        Cali_Antenna4        matlab.ui.control.CheckBox
        XEditField_4Label_2  matlab.ui.control.Label
        CaliX4               matlab.ui.control.NumericEditField
        YEditField_4Label_2  matlab.ui.control.Label
        CaliY4               matlab.ui.control.NumericEditField
        Cali_Antenna5        matlab.ui.control.CheckBox
        XEditField_5Label_2  matlab.ui.control.Label
        CaliX5               matlab.ui.control.NumericEditField
        YEditField_5Label_2  matlab.ui.control.Label
        CaliY5               matlab.ui.control.NumericEditField
        Cali_Antenna6        matlab.ui.control.CheckBox
        XEditField_6Label_2  matlab.ui.control.Label
        CaliX6               matlab.ui.control.NumericEditField
        YEditField_6Label_2  matlab.ui.control.Label
        CaliY6               matlab.ui.control.NumericEditField
        Cali_Antenna7        matlab.ui.control.CheckBox
        XEditField_7Label_2  matlab.ui.control.Label
        CaliX7               matlab.ui.control.NumericEditField
        YEditField_7Label_2  matlab.ui.control.Label
        CaliY7               matlab.ui.control.NumericEditField
        Cali_Antenna8        matlab.ui.control.CheckBox
        XEditField_8Label_2  matlab.ui.control.Label
        CaliX8               matlab.ui.control.NumericEditField
        YEditField_8Label_2  matlab.ui.control.Label
        CaliY8               matlab.ui.control.NumericEditField
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            close all;
            clc;
            addpath(".\SignalGenatation\");
            addpath(".\");
        end

        % Value changed function: ArrayType
        function ArrayTypeValueChanged(app, event)
            value = app.ArrayType.Value;
            if(strcmp(value, '方阵'))
                app.Label_6.Text = '间距';
            elseif(strcmp(value, '圆阵'))
                app.Label_6.Text = '半径';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global C;
            global DOA;  
            global SigInfo;
            global Lambda;
            Nsnapshot = 10000;
            C = 299792458; %光速
            Lambda = C/app.CentralFreq.Value;
            SigNum = 0;
            DOA = zeros(4, 2);
            SigInfo = zeros(4, 3);
            if(app.SelectSig1.Value)
                SigNum = SigNum + 1;
                DOA(1,:) = [app.Pitch1.Value, app.Azimuth1.Value];
                SigInfo(1,:) = [app.CwFreq1.Value, app.BW1.Value, app.SNR1.Value];
            end
            
            if(app.SelectSig2.Value)
                SigNum = SigNum + 1;
                DOA(2,:) = [app.Pitch2.Value, app.Azimuth2.Value];
                SigInfo(2,:) = [app.CwFreq2.Value, app.BW2.Value, app.SNR2.Value];
            end
            
            if(app.SelectSig3.Value)
                SigNum = SigNum + 1;
                DOA(3,:) = [app.Pitch3.Value, app.Azimuth3.Value];
                SigInfo(3,:) = [app.CwFreq3.Value, app.BW3.Value, app.SNR3.Value];
            end
            
            if(app.SelectSig4.Value)
                SigNum = SigNum + 1;
                DOA(4,:) = [app.Pitch4.Value, app.Azimuth4.Value];
                SigInfo(4,:) = [app.CwFreq4.Value, app.BW4.Value, app.SNR4.Value];
            end                     
            
            ArrayPosition = GeneratesAntennaArray(app.ArrayNum.Value, ...
                                                  app.Radius.Value, ...
                                                  app.ArrayType.Value);
            figure(1);
            plot3(ArrayPosition(:,1), ArrayPosition(:,2), zeros(app.ArrayNum.Value,1), 'o');
            
            SimSignal = SimSignalGen(SigNum, DOA, SigInfo, Nsnapshot, app.ChannelSNR.Value, ArrayPosition, Lambda);
            figure(2);
            plot(real(SimSignal(1,:)));
            
            sig_num = MUSIC_Simulation(SimSignal, ArrayPosition, Lambda);
                        
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global FilePath;
            global FileInfo;
            FilePath = uigetdir('F:\SpectrumDOA\SimData\', 'Select a directory');
            FileInfo = dir(fullfile(FilePath, '*.dat'));
            app.FileList.Items = {FileInfo.name};
        end

        % Button pushed function: Simu2
        function Simu2ButtonPushed(app, event)
            global FilePath;
            global C;
            global FFT_NUM;
            C = 299792458;
            FFT_NUM = 1024;
            TotalCh = 0;
            Position = zeros(0, 3);
            
            if(app.Antenna1.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X1.Value, app.Y1.Value, 0];
            end
            if(app.Antenna2.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X2.Value, app.Y2.Value, 0];
            end
            if(app.Antenna3.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X3.Value, app.Y3.Value, 0];
            end
            if(app.Antenna4.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X4.Value, app.Y4.Value, 0];
            end
            if(app.Antenna5.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X5.Value, app.Y5.Value, 0];
            end
            if(app.Antenna6.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X6.Value, app.Y6.Value, 0];
            end
            if(app.Antenna7.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X7.Value, app.Y7.Value, 0];
            end
            if(app.Antenna8.Value)
                TotalCh = TotalCh + 1;
                Position = [Position; app.X8.Value, app.Y8.Value, 0];
            end
            
            Path = [FilePath, '\', app.FileList.Value];
            Frame=SpectrumReadData(Path);
            
            Lambda = C/Frame.CentralFreq;
            MUSIC_Simulation(Frame.IQData, Position, Lambda, 'REAL', 'None');
        end

        % Button pushed function: Simu2_2
        function Simu2_2ButtonPushed(app, event)
            global FileInfo;
            global FFT_NUM;
            TotalCh = 6;
            FFT_NUM=1024;
            MaxPhaseDiff_Stream = zeros(TotalCh-1, size(FileInfo, 1));
            MeanPhaseDiff_Stream = zeros(TotalCh-1, size(FileInfo, 1));
            d1 = figure(1000);
            for i=1:size(FileInfo,1)
                Path = [FileInfo(i).folder, '\', FileInfo(i).name];
                Frame=SpectrumReadData(Path);
                figure(d1);
                for j = 1:TotalCh
                   [S, F, T] = spectrogram(Frame.IQData(j,:), hamming(FFT_NUM), FFT_NUM*0.5, FFT_NUM, 9.6e6);
                   subplot(1, TotalCh, j);
                   imagesc(F, T, fftshift(db(abs(S.')), 2));
                end
                PhaseDiff = PhaseAnalysis(Frame.IQData);
                MeanPhaseDiff_Stream(:,i) = PhaseDiff;
            end
            figure(1002);
            for i=1:TotalCh-1
                subplot(1, TotalCh, i);
                plot(MeanPhaseDiff_Stream(i,:));
            end
        end

        % Callback function
        function SelectFilePushed(app, event)
            
        end

        % Callback function
        function SelectFile_2ButtonPushed(app, event)
            
        end

        % Button pushed function: Button_4
        function SelectDirPushed(app, event)
            global DirInfo;
            DirecPath = uigetdir('F:\SpectrumDOA\SimData\', 'Select a directory');
            DirInfo = dir(fullfile(DirecPath));
            DirInfo = DirInfo(3:end);
            app.DirPath.Value = DirInfo.folder;
        end

        % Button pushed function: Calibrating
        function CalibratingButtonPushed(app, event)
            global DirInfo;
            Position = zeros(0, 3);
            if(app.SelBaseCh.Buttons(1,1).Value)
                BaseCh = 1;
            elseif(app.SelBaseCh.Buttons(1,2).Value)
                BaseCh = 2;
            elseif(app.SelBaseCh.Buttons(1,3).Value)
                BaseCh = 3;
            elseif(app.SelBaseCh.Buttons(1,4).Value)
                BaseCh = 4;
            elseif(app.SelBaseCh.Buttons(1,5).Value)
                BaseCh = 5;
            elseif(app.SelBaseCh.Buttons(1,6).Value)
                BaseCh = 6;
            elseif(app.SelBaseCh.Buttons(1,7).Value)
                BaseCh = 7;
            elseif(app.SelBaseCh.Buttons(1,8).Value)
                BaseCh = 8;
            end
            
            if(app.Cali_Antenna1.Value)
                Position = [Position; app.CaliX1.Value, app.CaliY1.Value, 0];
            end
            if(app.Cali_Antenna2.Value)
                Position = [Position; app.CaliX2.Value, app.CaliY2.Value, 0];
            end
            if(app.Cali_Antenna3.Value)
                Position = [Position; app.CaliX3.Value, app.CaliY3.Value, 0];
            end
            if(app.Cali_Antenna4.Value)
                Position = [Position; app.CaliX4.Value, app.CaliY4.Value, 0];
            end
            if(app.Cali_Antenna5.Value)
                Position = [Position; app.CaliX5.Value, app.CaliY5.Value, 0];
            end
            if(app.Cali_Antenna6.Value)
                Position = [Position; app.CaliX6.Value, app.CaliY6.Value, 0];
            end
            if(app.Cali_Antenna7.Value)
                Position = [Position; app.CaliX7.Value, app.CaliY7.Value, 0];
            end
            if(app.Cali_Antenna8.Value)
                Position = [Position; app.CaliX8.Value, app.CaliY8.Value, 0];
            end
            
            Coe = AngleCalibration(DirInfo, BaseCh, app.Cali_StartTime.Value, ...
                                   app.Cali_StopTime.Value, app.IQ_StartTime.Value, ...
                                   app.Fs.Value, app.InterMul.Value, Position, ...
                                   app.OutliersTh.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VirtualDataSim = uitab(app.TabGroup);
            app.VirtualDataSim.Title = '模拟信号仿真';
            app.BaseInfo = uipanel(app.VirtualDataSim);
            app.BaseInfo.Title = '基础信息';
            app.Label = uilabel(app.BaseInfo);
            app.Label.Text = '中心频率';
            app.CentralFreq = uieditfield(app.BaseInfo, 'numeric');
            app.CentralFreq.Value = 1575420000;
            app.Label_2 = uilabel(app.BaseInfo);
            app.Label_2.Text = '采样率';
            app.SampleFreq = uieditfield(app.BaseInfo, 'numeric');
            app.ChannelSNR = uieditfield(app.BaseInfo, 'numeric');
            app.ChannelSNR.Value = 45;
            app.Label_3 = uilabel(app.BaseInfo);
            app.Label_3.Text = '信道信噪比';
            app.ArrayInfo = uipanel(app.VirtualDataSim);
            app.ArrayInfo.Title = '阵元信息';
            app.Label_4 = uilabel(app.ArrayInfo);
            app.Label_4.Text = '阵型';
            app.ArrayType = uidropdown(app.ArrayInfo);
            app.ArrayType.Items = {'圆阵', '方阵'};
            app.ArrayType.ValueChangedFcn = createCallbackFcn(app, @ArrayTypeValueChanged, true);
            app.ArrayType.Value = '圆阵';
            app.Label_5 = uilabel(app.ArrayInfo);
            app.Label_5.Text = '阵元个数';
            app.ArrayNum = uieditfield(app.ArrayInfo, 'numeric');
            app.ArrayNum.Value = 8;
            app.Label_6 = uilabel(app.ArrayInfo);
            app.Label_6.Text = '半径';
            app.Radius = uieditfield(app.ArrayInfo, 'numeric');
            app.Radius.Value = 0.15;
            app.Button = uibutton(app.VirtualDataSim, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始仿真';
            app.SigInfo = uipanel(app.VirtualDataSim);
            app.SigInfo.Title = '信号信息';
            app.GridLayout9 = uigridlayout(app.SigInfo);
            app.Sig4 = uipanel(app.GridLayout9);
            app.Sig4.Title = '信号4';
            app.GridLayout6 = uigridlayout(app.Sig4);
            app.Pitch4 = uieditfield(app.GridLayout6, 'numeric');
            app.Pitch4.Value = 25;
            app.EditField_11Label_2 = uilabel(app.GridLayout6);
            app.EditField_11Label_2.Text = '俯仰角';
            app.Azimuth4 = uieditfield(app.GridLayout6, 'numeric');
            app.Azimuth4.Value = 220;
            app.EditField_12Label_2 = uilabel(app.GridLayout6);
            app.EditField_12Label_2.Text = '方位角';
            app.CwFreq4 = uieditfield(app.GridLayout6, 'numeric');
            app.CwFreq4.Value = 5000000;
            app.EditField_13Label_2 = uilabel(app.GridLayout6);
            app.EditField_13Label_2.Text = '载波频率';
            app.SNR4 = uieditfield(app.GridLayout6, 'numeric');
            app.SNR4.Value = 10;
            app.EditField_14Label_2 = uilabel(app.GridLayout6);
            app.EditField_14Label_2.Text = '信噪比';
            app.BW4 = uieditfield(app.GridLayout6, 'numeric');
            app.BW4.Value = 3000000;
            app.EditField_15Label_2 = uilabel(app.GridLayout6);
            app.EditField_15Label_2.Text = '带宽';
            app.SelectSig4 = uicheckbox(app.GridLayout6);
            app.SelectSig4.Text = '选择';
            app.Sig3 = uipanel(app.GridLayout9);
            app.Sig3.Title = '信号3';
            app.GridLayout12 = uigridlayout(app.Sig3);
            app.SelectSig3 = uicheckbox(app.GridLayout12);
            app.SelectSig3.Text = '选择';
            app.Pitch3 = uieditfield(app.GridLayout12, 'numeric');
            app.Pitch3.Value = 22;
            app.EditField_11Label = uilabel(app.GridLayout12);
            app.EditField_11Label.Text = '俯仰角';
            app.Azimuth3 = uieditfield(app.GridLayout12, 'numeric');
            app.Azimuth3.Value = 210;
            app.EditField_12Label = uilabel(app.GridLayout12);
            app.EditField_12Label.Text = '方位角';
            app.CwFreq3 = uieditfield(app.GridLayout12, 'numeric');
            app.CwFreq3.Value = 5000000;
            app.EditField_13Label = uilabel(app.GridLayout12);
            app.EditField_13Label.Text = '载波频率';
            app.SNR3 = uieditfield(app.GridLayout12, 'numeric');
            app.SNR3.Value = 10;
            app.EditField_14Label = uilabel(app.GridLayout12);
            app.EditField_14Label.Text = '信噪比';
            app.BW3 = uieditfield(app.GridLayout12, 'numeric');
            app.BW3.Value = 3000000;
            app.EditField_15Label = uilabel(app.GridLayout12);
            app.EditField_15Label.Text = '带宽';
            app.Sig2 = uipanel(app.GridLayout9);
            app.Sig2.Title = '信号2';
            app.GridLayout11 = uigridlayout(app.Sig2);
            app.SelectSig2 = uicheckbox(app.GridLayout11);
            app.SelectSig2.Text = '选择';
            app.Pitch2 = uieditfield(app.GridLayout11, 'numeric');
            app.Pitch2.Value = 37;
            app.EditField_6Label = uilabel(app.GridLayout11);
            app.EditField_6Label.Text = '俯仰角';
            app.Azimuth2 = uieditfield(app.GridLayout11, 'numeric');
            app.Azimuth2.Value = 120;
            app.EditField_7Label = uilabel(app.GridLayout11);
            app.EditField_7Label.Text = '方位角';
            app.SNR2 = uieditfield(app.GridLayout11, 'numeric');
            app.SNR2.Value = 10;
            app.EditField_9Label = uilabel(app.GridLayout11);
            app.EditField_9Label.Text = '信噪比';
            app.BW2 = uieditfield(app.GridLayout11, 'numeric');
            app.BW2.Value = 3000000;
            app.EditField_10Label = uilabel(app.GridLayout11);
            app.EditField_10Label.Text = '带宽';
            app.CwFreq2 = uieditfield(app.GridLayout11, 'numeric');
            app.CwFreq2.Value = 5000000;
            app.EditField_8Label = uilabel(app.GridLayout11);
            app.EditField_8Label.Text = '载波频率';
            app.Sig1 = uipanel(app.GridLayout9);
            app.Sig1.Title = '信号1';
            app.GridLayout10 = uigridlayout(app.Sig1);
            app.SelectSig1 = uicheckbox(app.GridLayout10);
            app.SelectSig1.Text = '选择';
            app.Label_7 = uilabel(app.GridLayout10);
            app.Label_7.Text = '俯仰角';
            app.Pitch1 = uieditfield(app.GridLayout10, 'numeric');
            app.Pitch1.Value = 31;
            app.EditField_2Label = uilabel(app.GridLayout10);
            app.EditField_2Label.Text = '方位角';
            app.Azimuth1 = uieditfield(app.GridLayout10, 'numeric');
            app.Azimuth1.Value = 112;
            app.Label_8 = uilabel(app.GridLayout10);
            app.Label_8.Text = '载波频率';
            app.CwFreq1 = uieditfield(app.GridLayout10, 'numeric');
            app.CwFreq1.Value = 5000000;
            app.EditField_4Label = uilabel(app.GridLayout10);
            app.EditField_4Label.Text = '信噪比';
            app.SNR1 = uieditfield(app.GridLayout10, 'numeric');
            app.SNR1.Value = 10;
            app.EditField_5Label = uilabel(app.GridLayout10);
            app.EditField_5Label.Text = '带宽';
            app.BW1 = uieditfield(app.GridLayout10, 'numeric');
            app.BW1.Value = 3000000;
            app.RealDataSim = uitab(app.TabGroup);
            app.RealDataSim.Title = '实际信号仿真';
            app.Panel = uipanel(app.RealDataSim);
            app.Panel.Title = '数据文件';
            app.FileList = uilistbox(app.Panel);
            app.FileList.Items = {};
            app.FileList.Value = {};
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '浏览';
            app.Panel_2 = uipanel(app.RealDataSim);
            app.Panel_2.Title = '坐标设置';
            app.Antenna1 = uicheckbox(app.Panel_2);
            app.Antenna1.Text = '天线1';
            app.Antenna1.Value = true;
            app.XEditFieldLabel = uilabel(app.Panel_2);
            app.XEditFieldLabel.Text = 'X';
            app.X1 = uieditfield(app.Panel_2, 'numeric');
            app.X1.Value = 0.15;
            app.YEditFieldLabel = uilabel(app.Panel_2);
            app.YEditFieldLabel.Text = 'Y';
            app.Y1 = uieditfield(app.Panel_2, 'numeric');
            app.Antenna2 = uicheckbox(app.Panel_2);
            app.Antenna2.Text = '天线2';
            app.Antenna2.Value = true;
            app.XEditField_2Label = uilabel(app.Panel_2);
            app.XEditField_2Label.Text = 'X';
            app.X2 = uieditfield(app.Panel_2, 'numeric');
            app.X2.Value = 0.106;
            app.YEditField_2Label = uilabel(app.Panel_2);
            app.YEditField_2Label.Text = 'Y';
            app.Y2 = uieditfield(app.Panel_2, 'numeric');
            app.Y2.Value = -0.106;
            app.Antenna3 = uicheckbox(app.Panel_2);
            app.Antenna3.Text = '天线3';
            app.Antenna3.Value = true;
            app.XEditField_3Label = uilabel(app.Panel_2);
            app.XEditField_3Label.Text = 'X';
            app.X3 = uieditfield(app.Panel_2, 'numeric');
            app.YEditField_3Label = uilabel(app.Panel_2);
            app.YEditField_3Label.Text = 'Y';
            app.Y3 = uieditfield(app.Panel_2, 'numeric');
            app.Y3.Value = -0.15;
            app.Antenna4 = uicheckbox(app.Panel_2);
            app.Antenna4.Text = '天线4';
            app.Antenna4.Value = true;
            app.XEditField_4Label = uilabel(app.Panel_2);
            app.XEditField_4Label.Text = 'X';
            app.X4 = uieditfield(app.Panel_2, 'numeric');
            app.X4.Value = -0.106;
            app.YEditField_4Label = uilabel(app.Panel_2);
            app.YEditField_4Label.Text = 'Y';
            app.Y4 = uieditfield(app.Panel_2, 'numeric');
            app.Y4.Value = -0.106;
            app.Antenna5 = uicheckbox(app.Panel_2);
            app.Antenna5.Text = '天线5';
            app.Antenna5.Value = true;
            app.XEditField_5Label = uilabel(app.Panel_2);
            app.XEditField_5Label.Text = 'X';
            app.X5 = uieditfield(app.Panel_2, 'numeric');
            app.X5.Value = -0.15;
            app.YEditField_5Label = uilabel(app.Panel_2);
            app.YEditField_5Label.Text = 'Y';
            app.Y5 = uieditfield(app.Panel_2, 'numeric');
            app.Antenna6 = uicheckbox(app.Panel_2);
            app.Antenna6.Text = '天线6';
            app.Antenna6.Value = true;
            app.XEditField_6Label = uilabel(app.Panel_2);
            app.XEditField_6Label.Text = 'X';
            app.X6 = uieditfield(app.Panel_2, 'numeric');
            app.X6.Value = -0.106;
            app.YEditField_6Label = uilabel(app.Panel_2);
            app.YEditField_6Label.Text = 'Y';
            app.Y6 = uieditfield(app.Panel_2, 'numeric');
            app.Y6.Value = 0.106;
            app.Antenna7 = uicheckbox(app.Panel_2);
            app.Antenna7.Text = '天线7';
            app.Antenna7.Value = true;
            app.XEditField_7Label = uilabel(app.Panel_2);
            app.XEditField_7Label.Text = 'X';
            app.X7 = uieditfield(app.Panel_2, 'numeric');
            app.YEditField_7Label = uilabel(app.Panel_2);
            app.YEditField_7Label.Text = 'Y';
            app.Y7 = uieditfield(app.Panel_2, 'numeric');
            app.Y7.Value = 0.15;
            app.Antenna8 = uicheckbox(app.Panel_2);
            app.Antenna8.Text = '天线8';
            app.Antenna8.Value = true;
            app.XEditField_8Label = uilabel(app.Panel_2);
            app.XEditField_8Label.Text = 'X';
            app.X8 = uieditfield(app.Panel_2, 'numeric');
            app.X8.Value = 0.106;
            app.YEditField_8Label = uilabel(app.Panel_2);
            app.YEditField_8Label.Text = 'Y';
            app.Y8 = uieditfield(app.Panel_2, 'numeric');
            app.Y8.Value = 0.106;
            app.Simu2 = uibutton(app.RealDataSim, 'push');
            app.Simu2.ButtonPushedFcn = createCallbackFcn(app, @Simu2ButtonPushed, true);
            app.Simu2.Text = '开始仿真';
            app.Simu2_2 = uibutton(app.RealDataSim, 'push');
            app.Simu2_2.ButtonPushedFcn = createCallbackFcn(app, @Simu2_2ButtonPushed, true);
            app.Simu2_2.Text = '相位差分析';
            app.Calibration = uitab(app.TabGroup);
            app.Calibration.Title = '标校';
            app.Calibrating = uibutton(app.Calibration, 'push');
            app.Calibrating.ButtonPushedFcn = createCallbackFcn(app, @CalibratingButtonPushed, true);
            app.Calibrating.Text = '开始校正';
            app.SelBaseCh = uibuttongroup(app.Calibration);
            app.SelBaseCh.Title = '基准通道';
            app.SelCH1 = uiradiobutton(app.SelBaseCh);
            app.SelCH1.Text = '通道1';
            app.SelCH1.Value = true;
            app.SelCH2 = uiradiobutton(app.SelBaseCh);
            app.SelCH2.Text = '通道2';
            app.SelCH3 = uiradiobutton(app.SelBaseCh);
            app.SelCH3.Text = '通道3';
            app.SelCH4 = uiradiobutton(app.SelBaseCh);
            app.SelCH4.Text = '通道4';
            app.SelCH5 = uiradiobutton(app.SelBaseCh);
            app.SelCH5.Text = '通道5';
            app.SelCH6 = uiradiobutton(app.SelBaseCh);
            app.SelCH6.Text = '通道6';
            app.SelCH7 = uiradiobutton(app.SelBaseCh);
            app.SelCH7.Text = '通道7';
            app.SelCH8 = uiradiobutton(app.SelBaseCh);
            app.SelCH8.Text = '通道8';
            app.Label_9 = uilabel(app.Calibration);
            app.Label_9.Text = '文件夹路径';
            app.DirPath = uieditfield(app.Calibration, 'text');
            app.Button_4 = uibutton(app.Calibration, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @SelectDirPushed, true);
            app.Button_4.Text = '浏览';
            app.TimePara = uipanel(app.Calibration);
            app.TimePara.Title = '时间参数配置';
            app.Label_11 = uilabel(app.TimePara);
            app.Label_11.Text = '校正数据起始时间';
            app.Cali_StartTime = uieditfield(app.TimePara, 'numeric');
            app.Label_12 = uilabel(app.TimePara);
            app.Label_12.Text = '校正数据结束时间';
            app.Cali_StopTime = uieditfield(app.TimePara, 'numeric');
            app.Cali_StopTime.Value = 0.07;
            app.IQEditFieldLabel = uilabel(app.TimePara);
            app.IQEditFieldLabel.Text = 'IQ数据起始时间';
            app.IQ_StartTime = uieditfield(app.TimePara, 'numeric');
            app.IQ_StartTime.Value = 0.5;
            app.Panel_4 = uipanel(app.Calibration);
            app.Panel_4.Title = '参数配置';
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '采样率';
            app.Fs = uieditfield(app.Panel_4, 'numeric');
            app.Fs.Value = 9600000;
            app.Label_13 = uilabel(app.Panel_4);
            app.Label_13.Text = '插值倍数';
            app.InterMul = uieditfield(app.Panel_4, 'numeric');
            app.InterMul.Value = 100;
            app.Label_14 = uilabel(app.Panel_4);
            app.Label_14.Text = '野值阈值';
            app.OutliersTh = uieditfield(app.Panel_4, 'numeric');
            app.OutliersTh.Value = 0.01;
            app.Panel_5 = uipanel(app.Calibration);
            app.Panel_5.Title = '坐标设置';
            app.Cali_Antenna1 = uicheckbox(app.Panel_5);
            app.Cali_Antenna1.Text = '天线1';
            app.Cali_Antenna1.Value = true;
            app.XEditFieldLabel_2 = uilabel(app.Panel_5);
            app.XEditFieldLabel_2.Text = 'X';
            app.CaliX1 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX1.Value = 0.098;
            app.YEditFieldLabel_2 = uilabel(app.Panel_5);
            app.YEditFieldLabel_2.Text = 'Y';
            app.CaliY1 = uieditfield(app.Panel_5, 'numeric');
            app.Cali_Antenna2 = uicheckbox(app.Panel_5);
            app.Cali_Antenna2.Text = '天线2';
            app.Cali_Antenna2.Value = true;
            app.XEditField_2Label_2 = uilabel(app.Panel_5);
            app.XEditField_2Label_2.Text = 'X';
            app.CaliX2 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX2.Value = 0.0693;
            app.YEditField_2Label_2 = uilabel(app.Panel_5);
            app.YEditField_2Label_2.Text = 'Y';
            app.CaliY2 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY2.Value = -0.0693;
            app.Cali_Antenna3 = uicheckbox(app.Panel_5);
            app.Cali_Antenna3.Text = '天线3';
            app.Cali_Antenna3.Value = true;
            app.XEditField_3Label_2 = uilabel(app.Panel_5);
            app.XEditField_3Label_2.Text = 'X';
            app.CaliX3 = uieditfield(app.Panel_5, 'numeric');
            app.YEditField_3Label_2 = uilabel(app.Panel_5);
            app.YEditField_3Label_2.Text = 'Y';
            app.CaliY3 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY3.Value = -0.15;
            app.Cali_Antenna4 = uicheckbox(app.Panel_5);
            app.Cali_Antenna4.Text = '天线4';
            app.Cali_Antenna4.Value = true;
            app.XEditField_4Label_2 = uilabel(app.Panel_5);
            app.XEditField_4Label_2.Text = 'X';
            app.CaliX4 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX4.Value = -0.0693;
            app.YEditField_4Label_2 = uilabel(app.Panel_5);
            app.YEditField_4Label_2.Text = 'Y';
            app.CaliY4 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY4.Value = -0.0693;
            app.Cali_Antenna5 = uicheckbox(app.Panel_5);
            app.Cali_Antenna5.Text = '天线5';
            app.Cali_Antenna5.Value = true;
            app.XEditField_5Label_2 = uilabel(app.Panel_5);
            app.XEditField_5Label_2.Text = 'X';
            app.CaliX5 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX5.Value = -0.098;
            app.YEditField_5Label_2 = uilabel(app.Panel_5);
            app.YEditField_5Label_2.Text = 'Y';
            app.CaliY5 = uieditfield(app.Panel_5, 'numeric');
            app.Cali_Antenna6 = uicheckbox(app.Panel_5);
            app.Cali_Antenna6.Text = '天线6';
            app.Cali_Antenna6.Value = true;
            app.XEditField_6Label_2 = uilabel(app.Panel_5);
            app.XEditField_6Label_2.Text = 'X';
            app.CaliX6 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX6.Value = -0.0693;
            app.YEditField_6Label_2 = uilabel(app.Panel_5);
            app.YEditField_6Label_2.Text = 'Y';
            app.CaliY6 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY6.Value = 0.0693;
            app.Cali_Antenna7 = uicheckbox(app.Panel_5);
            app.Cali_Antenna7.Text = '天线7';
            app.Cali_Antenna7.Value = true;
            app.XEditField_7Label_2 = uilabel(app.Panel_5);
            app.XEditField_7Label_2.Text = 'X';
            app.CaliX7 = uieditfield(app.Panel_5, 'numeric');
            app.YEditField_7Label_2 = uilabel(app.Panel_5);
            app.YEditField_7Label_2.Text = 'Y';
            app.CaliY7 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY7.Value = 0.098;
            app.Cali_Antenna8 = uicheckbox(app.Panel_5);
            app.Cali_Antenna8.Text = '天线8';
            app.Cali_Antenna8.Value = true;
            app.XEditField_8Label_2 = uilabel(app.Panel_5);
            app.XEditField_8Label_2.Text = 'X';
            app.CaliX8 = uieditfield(app.Panel_5, 'numeric');
            app.CaliX8.Value = 0.0693;
            app.YEditField_8Label_2 = uilabel(app.Panel_5);
            app.YEditField_8Label_2.Text = 'Y';
            app.CaliY8 = uieditfield(app.Panel_5, 'numeric');
            app.CaliY8.Value = 0.693;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Pitch1.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicSimAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end